classdef Mag_Field_Control < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SFieldJeffreyMEdeUIFigure  matlab.ui.Figure
        xFieldTable                matlab.ui.control.Table
        SweepFieldButton           matlab.ui.control.Button
        SaveSettingsButton         matlab.ui.control.Button
        LoadSettingsButton         matlab.ui.control.Button
        yFieldTable                matlab.ui.control.Table
        zFieldTable                matlab.ui.control.Table
        SaveSweepButton            matlab.ui.control.Button
        HelpButton                 matlab.ui.control.Button
        DropDownLabel              matlab.ui.control.Label
        DropDown                   matlab.ui.control.DropDown
        ToleranceGEditFieldLabel   matlab.ui.control.Label
        ToleranceGEditField        matlab.ui.control.NumericEditField
        RecordmsEditFieldLabel     matlab.ui.control.Label
        RecordmsEditField          matlab.ui.control.NumericEditField
        PauseButton                matlab.ui.control.Button
        FieldsPlot                 matlab.ui.control.UIAxes
        WindowsEditFieldLabel      matlab.ui.control.Label
        Window                     matlab.ui.control.NumericEditField
        AppendToEditFieldLabel     matlab.ui.control.Label
        AppendToEditField          matlab.ui.control.EditField
        ClearFileButton            matlab.ui.control.Button
        ProgressLabel              matlab.ui.control.Label
        ProgressGauge              matlab.ui.control.Gauge
    end

    properties (Access = public)
        xlimits = {}; % x field sweep rate limits
        ylimits = {}; % y field sweep rate limits
        zlimits = {}; % z field sweep rate limits
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Initialise tables with a row of empty cells
            app.xFieldTable.Data = {'' '' ''};
            app.yFieldTable.Data = {'' '' ''};
            app.zFieldTable.Data = {'' '' ''};
            
            % Load sweep rate limits
            fid = fopen('limits.txt');
            
            % Read limits from file
            while ~feof(fid)
                fileline = fgetl(fid);
                
                % Find which coil limits are for
                if contains(fileline,"%")
                    limits = fileline;
                    
                % Update x limits
                elseif (limits == "%x")
                    
                    vals = textscan(fileline,'%f');
                    app.xlimits = [app.xlimits; vals{1}'];
                    
                % Update y limits
                elseif (limits == "%y")
                    
                    vals = textscan(fileline,'%f');
                    app.ylimits = [app.ylimits; vals{1}'];
                    
                % Update z limits
                elseif (limits == "%z")
                    
                    vals = textscan(fileline,'%f');
                    app.zlimits = [app.zlimits; vals{1}];
                    
                elseif (limits == "%xmax")
                    
                    vals = textscan(fileline,'%f');
                    app.xlimits = [app.xlimits; vals{1}];
                    
                elseif (limits == "%ymax")
                    
                    vals = textscan(fileline,'%f');
                    app.ylimits = [app.ylimits; vals{1}];
                    
                elseif (limits == "%zmax")
                    
                    vals = textscan(fileline,'%f');
                    app.zlimits = [app.zlimits; vals{1}];
                end
            end
            
            fclose(fid); 
            
            % Number of rows in limits
            [x_limrows, c] = size(app.xlimits);
            [y_limrows, c] = size(app.ylimits);
            [z_limrows, c] = size(app.zlimits);
            
            % Convert limits' currents to G
            for i = 1:(x_limrows-1)
                app.xlimits{i}(1) = app.xlimits{i}(1)*app.xlimits{x_limrows}(2);
                app.xlimits{i}(2) = app.xlimits{i}(2)*app.xlimits{x_limrows}(2);
            end
            app.xlimits{x_limrows}(1) = app.xlimits{x_limrows}(1)*10000;
            
            for i = 1:(y_limrows-1)
                app.ylimits{i}(1) = app.ylimits{i}(1)*app.ylimits{y_limrows}(2);
                app.ylimits{i}(2) = app.ylimits{i}(2)*app.ylimits{y_limrows}(2);
            end
            app.ylimits{y_limrows}(1) = app.ylimits{y_limrows}(1)*10000;
            
            for i = 1:(z_limrows-1)
                app.zlimits{i}(1) = app.zlimits{i}(1)*app.zlimits{z_limrows}(2);
                app.zlimits{i}(2) = app.zlimits{i}(2)*app.zlimits{z_limrows}(2);
            end
            app.zlimits{z_limrows}(1) = app.zlimits{z_limrows}(1)*10000;
        end

        % Button pushed function: SweepFieldButton
        function sweep_fields(app, event)
            
            % Number of rows in tables
            [x_rows, c] = size(app.xFieldTable.Data);
            [y_rows, c] = size(app.yFieldTable.Data);
            [z_rows, c] = size(app.zFieldTable.Data);
            
            % Number of rows in limits
            [x_limrows, c] = size(app.xlimits);
            [y_limrows, c] = size(app.ylimits);
            [z_limrows, c] = size(app.zlimits);
            
            % Find the maximum sweep rates and check for overshooting of local sweep limits
            for i = 1:(x_rows-1) % Last row will be empty                
                % Get function describing magnetic field
                xstr = app.xFieldTable.Data{i};
                xfun = str2func(['@(t)',xstr]);
                
                % Number of steps
                x_num = str2num(app.xFieldTable.Data{i+2*x_rows});
                
                % Calculate timesteps
                x_tstep = str2num(app.xFieldTable.Data{i+x_rows})/x_num;
                rx_tstep = 1/x_tstep;
                
                % Check if any x sweep rates are higher than the local maximum
                % Record the highest x overshoot of a local maximum, if it occurs
                B_prev = xfun(0);
                for j = 1:x_num
                    
                    % Check B field is below maximum
                    B_target = xfun(j*x_tstep);
                    if (B_target > app.xlimits{x_limrows}(1))
                        % Create fatal error message
                        errordlg({strcat(['X target field of ', num2str(B_target),'G exceeds maximum of ', app.xlimits{x_limrows}(1), 'G.']) strcat(['Details: ', xstr, ' for t = ', num2str(j*x_tstep),'s.'])}, 'Fatal Error')
                        return ;
                    end
                    
                    % Calculate rate
                    rate = abs((B_target-B_prev)*rx_tstep);
                    
                    % Check if it exceeds local maximum
                    for i = 1:x_limrows
                        
                        % Check if this is the limit applicable to this field
                        if (max(abs(B_prev),abs(B_target)) <= app.xlimits{i}(1))
                            
                            % Check for overshoot
                            if (abs(rate) > app.xlimits{i}(2))
                                % Create fatal error message
                                errordlg({strcat(['X sweep rate of ', num2str(rate),'G/s exceeds maximum of ', num2str(app.xlimits{i}(2)), 'G/s.']) strcat(['Details: ', xstr, ' for t = ', num2str(j*x_tstep) ,'s.'])}, 'Fatal Error')
                                return ;
                            end
                            break
                        end
                    end
                    B_prev = B_target;
                end
            end
            
            for i = 1:(y_rows-1) % Last row will be empty                  
                % Get function describing magnetic field
                ystr = app.yFieldTable.Data{i};
                yfun = str2func(['@(t)',ystr]);
            
                % Number of steps
                y_num = str2num(app.yFieldTable.Data{i+2*y_rows});
                
                % Calculate timestep
                y_tstep = str2num(app.yFieldTable.Data{i+y_rows})/y_num;
                ry_tstep = 1/y_tstep;
                
                % Check if any y sweep rates are higher than the local maximum
                % Record the highest y overshoot of a local maximum, if it occurs
                B_prev = yfun(0);
                for j = 1:y_num
                    
                    % Check B field is below maximum
                    B_target = yfun(j*y_tstep);
                    if (B_target > app.ylimits{y_limrows}(1))
                        % Create fatal error message
                        errordlg({strcat(['Y target field of ', num2str(B_target),'G exceeds maximum of', num2str(app.ylimits{y_limrows}(1)), 'G.']) strcat(['Details: ', ystr, ' for t = ', num2str(j*y_tstep),'s.'])}, 'Fatal Error')
                        return ;
                    end
                    
                    % Calculate rate
                    rate = abs((B_target-B_prev)*ry_tstep);
                    
                    % Check if it exceeds local maximum
                    for i = 1:y_limrows
                        
                        % Check if this is the limit applicable to this field
                        
                        if (max(abs(B_prev),abs(B_target)) <= app.ylimits{i}(1))
                            
                            % Check for overshoot
                            if (abs(rate) > app.ylimits{i}(2))
                                % Create fatal error message
                                errordlg({strcat(['Y sweep rate of ', num2str(rate),'G/s exceeds maximum of ', num2str(app.ylimits{i}(2)), 'G/s.']) strcat(['Details: ', ystr, ' for t = ', num2str(j*y_tstep),'s.'])}, 'Fatal Error')
                                return ;
                            end
                            break
                        end
                    end
                    B_prev = B_target;
                end
            end
            
            for i = 1:(z_rows-1) % Last row will be empty                 
                % Get function describing magnetic field
                zstr = app.zFieldTable.Data{i};
                zfun = str2func(['@(t)',zstr]);
            
                % Number of steps
                z_num = str2num(app.zFieldTable.Data{i+2*z_rows});
                
                % Calculate timestep
                z_tstep = str2num(app.zFieldTable.Data{i+z_rows})/z_num;
                rz_tstep = 1/z_tstep;
                
                % Check if any z sweep rates are higher than the local maximum
                % Record the highest z overshoot of a local maximum, if it occurs
                B_prev = zfun(0);
                for j = 1:z_num
                    
                    % Check B field is below maximum
                    B_target = zfun(j*z_tstep);
                    if (B_target > app.zlimits{z_limrows}(1))
                        % Create fatal error message
                        errordlg({strcat(['Z target field of ', num2str(B_target),'G exceeds maximum of ', num2str(app.zlimits{z_limrows}(1)), 'G.']) strcat(['Details: ', zstr, ' for t = ', num2str(j*z_tstep) ,'s.'])}, 'Fatal Error')
                        return ;
                    end
                    
                    % Calculate rate
                    rate = abs((B_target-B_prev)*rz_tstep);
                    
                    % Check if rate exceeds local maximum
                    for i = 1:z_limrows
                        
                        % Check if this is the limit applicable to this field
                        
                        if (max(abs(B_prev),abs(B_target)) <= app.zlimits{i}(1))
                            
                            % Check for overshoot
                            if (abs(rate) > app.zlimits{i}(2))
                                % Create fatal error message
                                errordlg({strcat(['Z sweep rate of ', num2str(rate),'G/s exceeds maximum of ', app.zlimits{i}(2), 'G/s.']) strcat(['\nDetails: ', zstr, ' for t = ', num2str(j*z_tstep),'s.'])}, 'Fatal Error')
                                return ;
                            end
                            break
                        end
                    end
                    B_prev = B_target;
                end
            end
            
            % Create serial port objects
            xy = instrfind('Type', 'serial', 'Port', 'COM11', 'Tag', '');
            if isempty(xy)
                xy = serial('COM11');
            else
                fclose(xy);
                xy = xy(1);
            end
            fopen(xy);
            z = instrfind('Type', 'serial', 'Port', 'COM10', 'Tag', '');
            if isempty(z)
                z = serial('COM10');
            else
                fclose(z);
                z = z(1);
            end
            fopen(z);
            
            % Enable x and field remote control
            fprintf(xy,'%s\n', 'REMOTE');
            fscanf(xy);
            
            % Set units to Gauss
            fprintf(xy,'%s\n', 'CHAN 1');
            fscanf(xy);
            fprintf(xy,'%s\n', 'UNITS G');
            fscanf(xy);
            fprintf(xy,'%s\n', 'CHAN 2');
            fscanf(xy);
            fprintf(xy,'%s\n', 'UNITS G');
            fscanf(xy);
            
            % Enable z field remote control
            fprintf(z,'%s\n', 'REMOTE');
            fscanf(z);
            % Set units to Gauss
            fprintf(z,'%s\n', 'UNITS G');
            fscanf(z);
            
            % Number of rows in tables
            [x_rows, c] = size(app.xFieldTable.Data);
            [y_rows, c] = size(app.yFieldTable.Data);
            [z_rows, c] = size(app.zFieldTable.Data);
            
            % Number of rows in limits
            [x_limrows, c] = size(app.xlimits);
            [y_limrows, c] = size(app.ylimits);
            [z_limrows, c] = size(app.zlimits);
            
            % Handle empty table cases
            if (x_rows < 2)
                x_f = 1;
            else
                x_i = 1; % Step number
                x_steps = str2num(app.xFieldTable.Data{1+2*x_rows}); % Number steps in x row
                
                % Starting function
                xstr = app.xFieldTable.Data{1};
                xfun = str2func(['@(t)', xstr]);
                x_B = xfun(0); % First value of field
                
                % Switch to x field coils
                fprintf(xy,'%s\n', 'CHAN 1');
                fscanf(xy);
                
                % Get magnetic field value
                fprintf(xy,'%s\n', 'IOUT?');
                fscanf(xy);
                % Use regex to extract number from string
                Bx = 1000*str2double(regexp(fscanf(xy),'[-+]?[0-9]*\.?[0-9]+([eE][-+]?[0-9]+)?','match'));
                
                % Fast sweep to first x field if not already there
                if (abs(x_B-Bx) > app.ToleranceGEditField.Value)
                    if (x_B > Bx)
                        fprintf(xy,'%s\n', strcat(['ULIM ', num2str(x_B/1000)]));
                        fscanf(xy);
                        fprintf(xy,'%s\n', 'SWEEP UP FAST');
                        fscanf(xy);
                    else
                        fprintf(xy,'%s\n', strcat(['LLIM ', num2str(x_B/1000)]));
                        fscanf(xy);
                        fprintf(xy,'%s\n', 'SWEEP DOWN FAST');
                        fscanf(xy);
                    end
                    x_f = 0; % 1 if fast sweep completed
                else
                    x_f = 1; % 1 if fast sweep completed
                end
                
                x_dur = str2num(app.xFieldTable.Data{1+x_rows}); % Duration of x sweep
                x_tstep = x_dur/x_steps; % Timestep
            end
            if (y_rows < 2)
                y_f = 1;
            else
                y_i = 1; % Step number
                y_steps = str2num(app.yFieldTable.Data{1+2*y_rows}); % Number steps in y row
                
                % Starting function
                ystr = app.yFieldTable.Data{1};
                yfun = str2func(['@(t)', ystr]);
                y_B = yfun(0); % First value of field
                
                % Switch to x field coils
                fprintf(xy,'%s\n', 'CHAN 2');
                fscanf(xy);
                
                % Get magnetic field value
                fprintf(xy,'%s\n', 'IOUT?');
                fscanf(xy);
                % Use regex to extract number from string
                By = 1000*str2double(regexp(fscanf(xy),'[-+]?[0-9]*\.?[0-9]+([eE][-+]?[0-9]+)?','match'));
                
                % Fast sweep to first y field if not already there
                if (abs(y_B-By) > app.ToleranceGEditField.Value)
                    if (y_B > By)
                        fprintf(xy,'%s\n', strcat(['ULIM ', num2str(y_B/1000)]));
                        fscanf(xy);
                        fprintf(xy,'%s\n', 'SWEEP UP FAST');
                        fscanf(xy);
                    else
                        fprintf(xy,'%s\n', strcat(['LLIM ', num2str(y_B/1000)]));
                        fscanf(xy);
                        fprintf(xy,'%s\n', 'SWEEP DOWN FAST');
                        fscanf(xy);
                    end
                    y_f = 0; % 1 if fast sweep completed
                else
                    y_f = 1; % 1 if fast sweep completed
                end
                
                y_dur = str2num(app.yFieldTable.Data{1+y_rows}); % Duration of y sweep
                y_tstep = y_dur/y_steps; % Timestep
            end
            if (z_rows < 2)
                z_f = 1;
            else
                z_i = 1;
                z_steps = str2num(app.zFieldTable.Data{1+2*z_rows}); % Number steps in z row
                
                % Starting function
                zstr = app.zFieldTable.Data{1};
                zfun = str2func(['@(t)', zstr]);
                z_B = zfun(0); % First value of field
                
                % Get magnetic field value
                fprintf(z,'%s\n', 'IOUT?');
                fscanf(z);
                % Use regex to extract number from string
                Bz = 1000*str2double(regexp(fscanf(z),'[-+]?[0-9]*\.?[0-9]+([eE][-+]?[0-9]+)?','match'));
                
                % Fast sweep to first z field if not already there
                if (abs(z_B-Bz) > app.ToleranceGEditField.Value)
                    if (z_B > Bz)
                        fprintf(z,'%s\n', strcat(['ULIM ', num2str(z_B/1000)]));
                        fscanf(z);
                        fprintf(z,'%s\n', 'SWEEP UP FAST');
                        fscanf(z);
                    else
                        fprintf(z,'%s\n', strcat(['LLIM ', num2str(z_B/1000)]));
                        fscanf(z);
                        fprintf(z,'%s\n', 'SWEEP DOWN FAST');
                        fscanf(z);
                    end
                    z_f = 0; % 1 if fast sweep completed
                else
                    z_f = 1; % 1 if fast sweep completed
                end
                
                z_dur = str2num(app.zFieldTable.Data{1+z_rows}); % Duration of z sweep
                z_tstep = z_dur/z_steps; % Timestep
            end
            
            x_r = 1; y_r = 1; z_r = 1; % Table row being executed
            
            % Open file to save x, y and z data
            fileHandle = fopen(strrep(app.AppendToEditField.Value, "\", "/"), 'a');
            
            % Metadata
            fprintf(fileHandle, "%s\n", datestr(datetime('now')));
            % FLAG get function metadata
            
            % Column titles
            fprintf(fileHandle, "%s %s %s %s %s %s\n", ['Time (s)' 'x Field (G)' 'Time (s)' 'y Field (G)' 'Time (s)' 'z Field (G)']);
            
            fclose(fileHandle)
            
            % Store field measurements within window in arrays
            x_times = []; x_fields = []; y_times = []; y_fields = []; z_times = []; z_fields = [];
            
            % Field measurement made in current interation
            x_time = -1; x_field = 0; y_time = -1; y_field = 0; z_time = -1; z_field = 0; % time = -1 will be used as a flag to indicate lack of measurement
            
            %Output fields outside the time window to temporary files. This prevents memory issues for long sweep times
            xfile = fopen('xfile.txt', 'w');
            yfile = fopen('yfile.txt', 'w');
            zfile = fopen('zfile.txt', 'w');
            
%             % Perform initialisation sweep to get past initial current spike
%             fprintf(z,'%s\n', 'IOUT?');
%             fscanf(z);
%             % Use regex to extract number from string
%             B = 1000*str2double(regexp(fscanf(z),'[-+]?[0-9]*\.?[0-9]+([eE][-+]?[0-9]+)?','match'));
%             
%             % Sweep to target
%             if (B > 0)
%                 fprintf(z,'%s\n', strcat(['ULIM ', num2str(0)]));
%                 fscanf(z);
%                 fprintf(z,'%s\n', 'SWEEP UP');
%                 fscanf(z);
%             else
%                 fprintf(z,'%s\n', strcat(['LLIM ', num2str(0)]));
%                 fscanf(z);
%                 fprintf(z,'%s\n', 'SWEEP DOWN');
%                 fscanf(z);
%             end
%             
%             pause(10)
% 
%             % Perform initialisation sweep to get past initial current spike
%             fprintf(z,'%s\n', 'IOUT?');
%             fscanf(z);
%             % Use regex to extract number from string
%             B = 1000*str2double(regexp(fscanf(z),'[-+]?[0-9]*\.?[0-9]+([eE][-+]?[0-9]+)?','match'));
%             
%             while (abs(B) > app.ToleranceGEditField.Value)
%                 pause(2)
%                 % Perform initialisation sweep to get past initial current spike
%                 fprintf(z,'%s\n', 'IOUT?');
%                 fscanf(z);
%                 % Use regex to extract number from string
%                 B = 1000*str2double(regexp(fscanf(z),'[-+]?[0-9]*\.?[0-9]+([eE][-+]?[0-9]+)?','match'));
%             end
%             pause(10)

            tic; % Start clock
            x_t0 = 0; y_t0 = 0; z_t0  = 0; % Time row sweep started relative to tic
            
            while (x_r < x_rows || y_r < y_rows || z_r < z_rows)
                if (x_f+y_f+z_f < 3)
                    if ~x_f
                        % Switch to x field coils
                        fprintf(xy,'%s\n', 'CHAN 1');
                        fscanf(xy);
                        
                        % Get magnetic field value
                        fprintf(xy,'%s\n', 'IOUT?');
                        fscanf(xy);
                        % Use regex to extract number from string
                        B = 1000*str2double(regexp(fscanf(xy),'[-+]?[0-9]*\.?[0-9]+([eE][-+]?[0-9]+)?','match'));
                        
                        x_time = toc;
                        x_field = B;
                        
                        if (abs(B-x_B) <= app.ToleranceGEditField.Value)
                            x_f = 1;
                            x_t0 = toc;
                        end
                    end
                    if ~y_f
                        % Switch to y field coils
                        fprintf(xy,'%s\n', 'CHAN 2');
                        fscanf(xy);
                        
                        % Get magnetic field value
                        fprintf(xy,'%s\n', 'IOUT?');
                        fscanf(xy);
                        % Use regex to extract number from string
                        B = 1000*str2double(regexp(fscanf(xy),'[-+]?[0-9]*\.?[0-9]+([eE][-+]?[0-9]+)?','match'));
                        
                        y_time = toc;
                        y_field = B;
                        
                        if (abs(B-y_B) <= app.ToleranceGEditField.Value)
                            y_f = 1;
                            y_t0 = toc;
                        end
                    end
                    if ~z_f                        
                        % Get magnetic field value
                        fprintf(z,'%s\n', 'IOUT?');
                        fscanf(z);
                        % Use regex to extract number from string
                        B = 1000*str2double(regexp(fscanf(z),'[-+]?[0-9]*\.?[0-9]+([eE][-+]?[0-9]+)?','match'));
                        
                        z_time = toc;
                        z_field = B;                        
                        
                        if (abs(B-z_B) <= app.ToleranceGEditField.Value)
                            z_f = 1;
                            z_t0 = toc;
                        end
                    end
                else
                    if (x_r < x_rows)
                        if (toc-x_t0 >= (x_i-1)*x_tstep)
                            % Switch to x field coils
                            fprintf(xy,'%s\n', 'CHAN 1');
                            fscanf(xy);
                            
                            % Get magnetic field value
                            fprintf(xy,'%s\n', 'IOUT?');
                            fscanf(xy);
                            
                            % Use regex to extract number from string
                            Bx = 1000*str2double(regexp(fscanf(xy),'[-+]?[0-9]*\.?[0-9]+([eE][-+]?[0-9]+)?','match'));

                            x_time = toc;
                            x_field = Bx;
                            
                            % Check if this is the last step of row
                            if (x_i > x_steps)
                                x_r = x_r+1;
                                % if there are more rows 
                                if (x_r < x_rows)
                                    % Set up for next sweep
                                    x_i = 1;
                                    xstr = app.xFieldTable.Data{x_r};
                                    xfun = str2func(['@(t)', xstr]); % Field generating function
                                    x_steps = str2num(app.xFieldTable.Data{x_r+2*x_rows}); % Number steps in x row
                                    x_dur = str2num(app.xFieldTable.Data{x_r+x_rows});  % Duration of x sweep
                                    x_tstep = x_dur/x_steps; % Time increment between steps
                                    
                                    % Initiate fast sweep to next function start if there is a discontinuity in the field train
                                    x_B = xfun(0); % Target field
                                    if (abs(x_B-Bx) > app.ToleranceGEditField.Value)
                                        x_f = 0;
                                        % Fast sweep to target field
                                        if (x_B > Bx)
                                            fprintf(xy,'%s\n', strcat(['ULIM ', num2str(x_B/1000)]));
                                            fscanf(xy);
                                            fprintf(xy,'%s\n', 'SWEEP UP FAST');
                                            fscanf(xy);
                                        else
                                            fprintf(xy,'%s\n', strcat(['LLIM ', num2str(x_B/1000)]));
                                            fscanf(xy);
                                            fprintf(xy,'%s\n', 'SWEEP DOWN FAST');
                                            fscanf(xy);
                                        end
                                    end
                                end
                            else                                
                                new_B = xfun(x_i*x_tstep); % Target
                                rate = abs(new_B - Bx)/x_tstep; % Sweep rate
                                
                                % Check that rate is achievable
                                o_Bx = 0; ilim_Bx = 0; rlim_Bx = 0; rbound_Bx = 0;
                                for i = 1:x_limrows
                                    if (abs(Bx) <= app.xlimits{i}(1))
                                        % Check for overshoot
                                        if (abs(rate) > app.xlimits{i}(2))
                                            o_Bx = 1; % Note overshoot
                                        end
                                        ilim_Bx = i;
                                        rbound_Bx = app.xlimits{i}(1); % Bounding field
                                        rlim_Bx = app.xlimits{i}(2); % Get limit
                                        break
                                    end
                                end
                                o_newB = 0; ilim_newB = 0; rlim_newB = 0; rbound_newB = 0;
                                for i = 1:x_limrows
                                    if (abs(new_B) <= app.xlimits{i}(1))
                                        % Check for overshoot
                                        if (abs(rate) > app.xlimits{i}(2))
                                            o_newB = 1; % Note overshoot
                                        end
                                        ilim_newB = i;
                                        rbound_newB = app.xlimits{i}(1); % Bounding field
                                        rlim_newB = app.xlimits{i}(2); % Get limit
                                        break
                                    end
                                end
                                
                                % No overshoot
                                if (o_Bx+o_newB == 0)
                                    fprintf(xy,'%s\n', strcat(['Rate ', num2str(ilim_Bx), ' ', num2str(rate)]));
                                    fscanf(xy);
                                    if ~(ilim_Bx == ilim_newB)
                                        fprintf(xy,'%s\n', strcat(['Rate ', num2str(ilim_newB), ' ', num2str(rate)]));
                                        fscanf(xy);
                                    end
                                % 1 Overshoots: Set it to limiting rate and increase the other's rate to compensate
                                elseif (o_Bx+o_newB == 1)
                                    if (o_Bx == 1)
                                        % Limit overshooting rate
                                        fprintf(xy,'%s\n', strcat(['Rate ', num2str(ilim_Bx), ' ', num2str(rlim_Bx)]));
                                        fscanf(xy);
                                        
                                        % Attempt seesaw balancing to prevent deviation
                                        seesaw_rate = (rbound_newB-new_B)/(x_tstep+((rbound_newB-Bx)/rlim_Bx));
                                        if (seesaw_rate > rlim_newB)
                                            % Limit overshooting seesaw
                                            fprintf(xy,'%s\n', strcat(['Rate ', num2str(ilim_newB), ' ', num2str(rlim_newB)]));
                                            fscanf(xy);
                                        else
                                            % Apply seesaw
                                            fprintf(xy,'%s\n', strcat(['Rate ', num2str(ilim_newB), ' ', num2str(seesaw_rate)]));
                                            fscanf(xy);
                                        end
                                    else
                                        % Limit overshooting rate
                                        fprintf(xy,'%s\n', strcat(['Rate ', num2str(ilim_newB), ' ', num2str(rlim_newB)]));
                                        fscanf(xy);
                                        
                                        % Attempt seesaw balancing to prevent deviation
                                        seesaw_rate = (rbound_Bx-Bx)/(x_tstep+((rbound_Bx-new_B)/rlim_newB));
                                        if (seesaw_rate > rlim_Bx)
                                            % Limit overshooting seesaw
                                            fprintf(xy,'%s\n', strcat(['Rate ', num2str(ilim_Bx), ' ', num2str(rlim_Bx)]));
                                            fscanf(xy);
                                        else
                                            % Apply seesaw
                                            fprintf(xy,'%s\n', strcat(['Rate ', num2str(ilim_Bx), ' ', num2str(seesaw_rate)]));
                                            fscanf(xy);
                                        end
                                    end
                                % Both overshoot
                                else
                                    fprintf(xy,'%s\n', strcat(['Rate ', num2str(ilim_Bx), ' ', num2str(rlim_Bx)]));
                                    fscanf(xy);
                                    if ~(ilim_Bx == ilim_newB)
                                        fprintf(xy,'%s\n', strcat(['Rate ', num2str(ilim_newB), ' ', num2str(rlim_newB)]));
                                        fscanf(xy);
                                    end
                                end
                                
                                % Sweep to target
                                if (new_B > x_B)
                                    fprintf(xy,'%s\n', strcat(['ULIM ', num2str(new_B/1000)]));
                                    fscanf(xy);
                                    fprintf(xy,'%s\n', 'SWEEP UP');
                                    fscanf(xy);
                                elseif (new_B < x_B)
                                    fprintf(xy,'%s\n', strcat(['LLIM ', num2str(new_B/1000)]));
                                    fscanf(xy);
                                    fprintf(xy,'%s\n', 'SWEEP DOWN');
                                    fscanf(xy);
                                end
                                
                                x_B = new_B;
                                
                                % Increment step
                                x_i = x_i + 1;
                            end
                        end
                    end
                    
                    if (y_r < y_rows)
                        if (toc-y_t0 >= (y_i-1)*y_tstep)
                            % Switch to y field coils
                            fprintf(xy,'%s\n', 'CHAN 2');
                            fscanf(xy);
                            
                            % Get magnetic field value
                            fprintf(xy,'%s\n', 'IOUT?');
                            fscanf(xy);
                            % Use regex to extract number from string
                            By = 1000*str2double(regexp(fscanf(xy),'[-+]?[0-9]*\.?[0-9]+([eE][-+]?[0-9]+)?','match'));
                            
                            y_time = toc;
                            y_field = By;
                            
                            % Check if this is the last step of row
                            if (y_i > y_steps)
                                y_r = y_r+1;
                                % if there are more rows 
                                if (y_r < y_rows)
                                    % Set up for next sweep
                                    y_i = 1;
                                    ystr = app.yFieldTable.Data{y_r};
                                    yfun = str2func(['@(t)', ystr]); % Field generating function
                                    y_steps = str2num(app.yFieldTable.Data{y_r+2*y_rows}); % Number steps in y row
                                    y_dur = str2num(app.yFieldTable.Data{y_r+y_rows});  % Duration of y sweep
                                    y_tstep = y_dur/y_steps; % Time increment between steps
                                    
                                    % Initiate fast sweep to next function start if there is a discontinuity in the field train
                                    y_B = yfun(0); % Target field
                                    if (abs(y_B-By) > app.ToleranceGEditField.Value)
                                        y_f = 0;
                                        % Fast sweep to target field
                                        if (y_B > By)
                                            fprintf(xy,'%s\n', strcat(['ULIM ', num2str(y_B/1000)]));
                                            fscanf(xy);
                                            fprintf(xy,'%s\n', 'SWEEP UP FAST');
                                            fscanf(xy);
                                        else
                                            fprintf(xy,'%s\n', strcat(['LLIM ', num2str(y_B/1000)]));
                                            fscanf(xy);
                                            fprintf(xy,'%s\n', 'SWEEP DOWN FAST');
                                            fscanf(xy);
                                        end
                                    end
                                end
                            else                                
                                new_B = yfun(y_i*y_tstep); % Target
                                rate = abs(new_B - By)/y_tstep; % Sweep rate
                                
                                % Check that rate is achievable
                                o_By = 0; ilim_By = 0; rlim_By = 0; rbound_By = 0;
                                for i = 1:y_limrows
                                    if (abs(By) <= app.ylimits{i}(1))
                                        % Check for overshoot
                                        if (abs(rate) > app.ylimits{i}(2))
                                            o_By = 1; % Note overshoot
                                        end
                                        ilim_By = i;
                                        rbound_By = app.ylimits{i}(1); % Bounding field
                                        rlim_By = app.ylimits{i}(2); % Get limit
                                        break
                                    end
                                end
                                o_newB = 0; ilim_newB = 0; rlim_newB = 0; rbound_newB = 0;
                                for i = 1:y_limrows
                                    if (abs(new_B) <= app.ylimits{i}(1))
                                        % Check for overshoot
                                        if (abs(rate) > app.ylimits{i}(2))
                                            o_newB = 1; % Note overshoot
                                        end
                                        ilim_newB = i;
                                        rbound_newB = app.ylimits{i}(1); % Bounding field
                                        rlim_newB = app.ylimits{i}(2); % Get limit
                                        break
                                    end
                                end
                                
                                % No overshoot
                                if (o_By+o_newB == 0)
                                    fprintf(xy,'%s\n', strcat(['Rate ', num2str(ilim_By), ' ', num2str(rate)]));
                                    fscanf(xy);
                                    if ~(ilim_By == ilim_newB)
                                        fprintf(xy,'%s\n', strcat(['Rate ', num2str(ilim_newB), ' ', num2str(rate)]));
                                        fscanf(xy);
                                    end
                                % 1 Overshoots: Set it to limiting rate and increase the other's rate to compensate
                                elseif (o_By+o_newB == 1)
                                    if (o_By == 1)
                                        % Limit overshooting rate
                                        fprintf(xy,'%s\n', strcat(['Rate ', num2str(ilim_By), ' ', num2str(rlim_By)]));
                                        fscanf(xy);
                                        
                                        % Attempt seesaw balancing to prevent deviation
                                        seesaw_rate = (rbound_newB-new_B)/(y_tstep+((rbound_newB-By)/rlim_By));
                                        if (seesaw_rate > rlim_newB)
                                            % Limit overshooting seesaw
                                            fprintf(xy,'%s\n', strcat(['Rate ', num2str(ilim_newB), ' ', num2str(rlim_newB)]));
                                            fscanf(xy);
                                        else
                                            % Apply seesaw
                                            fprintf(xy,'%s\n', strcat(['Rate ', num2str(ilim_newB), ' ', num2str(seesaw_rate)]));
                                            fscanf(xy);
                                        end
                                    else
                                        % Limit overshooting rate
                                        fprintf(xy,'%s\n', strcat(['Rate ', num2str(ilim_newB), ' ', num2str(rlim_newB)]));
                                        fscanf(xy);
                                        
                                        % Attempt seesaw balancing to prevent deviation
                                        seesaw_rate = (rbound_By-By)/(y_tstep+((rbound_By-new_B)/rlim_newB));
                                        if (seesaw_rate > rlim_By)
                                            % Limit overshooting seesaw
                                            fprintf(xy,'%s\n', strcat(['Rate ', num2str(ilim_By), ' ', num2str(rlim_By)]));
                                            fscanf(xy);
                                        else
                                            % Apply seesaw
                                            fprintf(xy,'%s\n', strcat(['Rate ', num2str(ilim_By), ' ', num2str(seesaw_rate)]));
                                            fscanf(xy);
                                        end
                                    end
                                % Both overshoot
                                else
                                    fprintf(xy,'%s\n', strcat(['Rate ', num2str(ilim_By), ' ', num2str(rlim_By)]));
                                    fscanf(xy);
                                    if ~(ilim_By == ilim_newB)
                                        fprintf(xy,'%s\n', strcat(['Rate ', num2str(ilim_newB), ' ', num2str(rlim_newB)]));
                                        fscanf(xy);
                                    end
                                end
                                
                                % Sweep to target
                                if (new_B > y_B)
                                    fprintf(xy,'%s\n', strcat(['ULIM ', num2str(new_B/1000)]));
                                    fscanf(xy);
                                    fprintf(xy,'%s\n', 'SWEEP UP');
                                    fscanf(xy);
                                elseif (new_B < y_B)
                                    fprintf(xy,'%s\n', strcat(['LLIM ', num2str(new_B/1000)]));
                                    fscanf(xy);
                                    fprintf(xy,'%s\n', 'SWEEP DOWN');
                                    fscanf(xy);
                                end
                                
                                y_B = new_B;
                                
                                % Increment step
                                y_i = y_i + 1;
                            end
                        end
                    end
                    
                    if (z_r < z_rows)
                        if (toc-z_t0 >= (z_i-1)*z_tstep)                            
                            % Get magnetic field value
                            fprintf(z,'%s\n', 'IOUT?');
                            fscanf(z);
                            % Use regex to extract number from string
                            Bz = 1000*str2double(regexp(fscanf(z),'[-+]?[0-9]*\.?[0-9]+([eE][-+]?[0-9]+)?','match'));
                            
                            z_time = toc;
                            z_field = Bz;
                            
                            % Check if this is the last step of row
                            if (z_i > z_steps)
                                z_r = z_r+1;
                                % if there are more rows 
                                if (z_r < z_rows)
                                    % Set up for next sweep
                                    z_i = 1;
                                    zstr = app.zFieldTable.Data{z_r};
                                    zfun = str2func(['@(t)', zstr]); % Field generating function
                                    z_steps = str2num(app.zFieldTable.Data{z_r+2*z_rows}); % Number steps in z row
                                    z_dur = str2num(app.zFieldTable.Data{z_r+z_rows});  % Duration of z sweep
                                    z_tstep = z_dur/z_steps; % Time increment between steps
                                    
                                    % Initiate fast sweep to next function start if there is a discontinuity in the field train
                                    z_B = zfun(0); % Target field
                                    if (abs(z_B-Bz) > app.ToleranceGEditField.Value)
                                        z_f = 0;
                                        % Fast sweep to target field
                                        if (z_B > Bz)
                                            fprintf(z,'%s\n', strcat(['ULIM ', num2str(z_B/1000)]));
                                            fscanf(z);
                                            fprintf(z,'%s\n', 'SWEEP UP FAST');
                                            fscanf(z);
                                        else
                                            fprintf(z,'%s\n', strcat(['LLIM ', num2str(z_B/1000)]));
                                            fscanf(z);
                                            fprintf(z,'%s\n', 'SWEEP DOWN FAST');
                                            fscanf(z);
                                        end
                                    end
                                end
                            else
                                new_B = zfun(z_i*z_tstep); % Target
                                rate = abs(new_B - Bz)/z_tstep; % Sweep rate
                                
                                % Check that rate is achievable
                                o_Bz = 0; ilim_Bz = 0; rlim_Bz = 0; rbound_Bz = 0;
                                for i = 1:z_limrows
                                    if (abs(Bz) <= app.zlimits{i}(1))
                                        % Check for overshoot
                                        if (abs(rate) > app.zlimits{i}(2))
                                            o_Bz = 1; % Note overshoot
                                        end
                                        ilim_Bz = i;
                                        rbound_Bz = app.zlimits{i}(1); % Bounding field
                                        rlim_Bz = app.zlimits{i}(2); % Get limit
                                        break
                                    end
                                end
                                o_newB = 0; ilim_newB = 0; rlim_newB = 0; rbound_newB = 0;
                                for i = 1:z_limrows
                                    if (abs(new_B) <= app.zlimits{i}(1))
                                        % Check for overshoot
                                        if (abs(rate) > app.zlimits{i}(2))
                                            o_newB = 1; % Note overshoot
                                        end
                                        ilim_newB = i;
                                        rbound_newB = app.zlimits{i}(1); % Bounding field
                                        rlim_newB = app.zlimits{i}(2); % Get limit
                                        break
                                    end
                                end
                                
                                % No overshoot
                                if (o_Bz+o_newB == 0)
                                    fprintf(z,'%s\n', strcat(['Rate ', num2str(ilim_Bz), ' ', num2str(rate)]));
                                    fscanf(z);
                                    if ~(ilim_Bz == ilim_newB)
                                        fprintf(z,'%s\n', strcat(['Rate ', num2str(ilim_newB), ' ', num2str(rate)]));
                                        fscanf(z);
                                    end
                                % 1 Overshoots: Set it to limiting rate and increase the other's rate to compensate
                                elseif (o_Bz+o_newB == 1)
                                    if (o_Bz == 1)
                                        % Limit overshooting rate
                                        fprintf(z,'%s\n', strcat(['Rate ', num2str(ilim_Bz), ' ', num2str(rlim_Bz)]));
                                        fscanf(z);
                                        
                                        % Attempt seesaw balancing to prevent deviation
                                        seesaw_rate = (rbound_newB-new_B)/(z_tstep+((rbound_newB-Bz)/rlim_Bz));
                                        if (seesaw_rate > rlim_newB)
                                            % Limit overshooting seesaw
                                            fprintf(z,'%s\n', strcat(['Rate ', num2str(ilim_newB), ' ', num2str(rlim_newB)]));
                                            fscanf(z);
                                        else
                                            % Apply seesaw
                                            fprintf(z,'%s\n', strcat(['Rate ', num2str(ilim_newB), ' ', num2str(seesaw_rate)]));
                                            fscanf(z);
                                        end
                                    else
                                        % Limit overshooting rate
                                        fprintf(z,'%s\n', strcat(['Rate ', num2str(ilim_newB), ' ', num2str(rlim_newB)]));
                                        fscanf(z);
                                        
                                        % Attempt seesaw balancing to prevent deviation
                                        seesaw_rate = (rbound_Bz-Bz)/(z_tstep+((rbound_Bz-new_B)/rlim_newB));
                                        if (seesaw_rate > rlim_Bz)
                                            % Limit overshooting seesaw
                                            fprintf(z,'%s\n', strcat(['Rate ', num2str(ilim_Bz), ' ', num2str(rlim_Bz)]));
                                            fscanf(z);
                                        else
                                            % Apply seesaw
                                            fprintf(z,'%s\n', strcat(['Rate ', num2str(ilim_Bz), ' ', num2str(seesaw_rate)]));
                                            fscanf(z);
                                        end
                                    end
                                % Both overshoot
                                else
                                    fprintf(z,'%s\n', strcat(['Rate ', num2str(ilim_Bz), ' ', num2str(rlim_Bz)]));
                                    fscanf(z);
                                    if ~(ilim_Bz == ilim_newB)
                                        fprintf(z,'%s\n', strcat(['Rate ', num2str(ilim_newB), ' ', num2str(rlim_newB)]));
                                        fscanf(z);
                                    end
                                end
                                
                                % Sweep to target
                                if (new_B > z_B)
                                    fprintf(z,'%s\n', strcat(['ULIM ', num2str(new_B/1000)]));
                                    fscanf(z);
                                    fprintf(z,'%s\n', 'SWEEP UP');
                                    fscanf(z);
                                elseif (new_B < z_B)
                                    fprintf(z,'%s\n', strcat(['LLIM ', num2str(new_B/1000)]));
                                    fscanf(z);
                                    fprintf(z,'%s\n', 'SWEEP DOWN');
                                    fscanf(z);
                                end
                                
                                z_B = new_B;
                                
                                % Increment step
                                z_i = z_i + 1;
                            end
                        end
                    end
                end
                
                % Update graph and output to files
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                % Check for x measurement
                if ~(x_time == -1)
                    len = length(x_times);
                    if (len > 0)
                        
                        % Find times outside the time window
                        cutoff_t = x_time-app.Window.Value;
                        j = 0;
                        for i = 1:len
                            if (x_times(i) > cutoff_t)
                                break
                            else
                                j = j + 1;
                            end
                        end
                        
                        % Write times outside the time window to file
                        if (j > 0)
                            for i = 1:j
                                fprintf(xfile, '%f %f\n', [x_times(i) x_fields(i)]);
                            end
                        end
                        
                        % Remove cutoff times from window arrays; append new value to window arrays
                        x_times = [x_times((1+j):len); x_time];
                        x_fields = [x_fields((1+j):len); x_field];
                        
                    else
                        x_times = [x_time];
                        x_fields = [x_field];
                    end
                    
                    x_time = -1;
                end
                
                % Check for y measurement
                if ~(y_time == -1)
                    len = length(y_times);
                    if (len > 0)
                        
                        % Find times outside the time window
                        cutoff_t = y_time-app.Window.Value;
                        j = 0;
                        for i = 1:len
                            if (y_times(i) > cutoff_t)
                                break
                            else
                                j = j + 1;
                            end
                        end
                        
                        % Write times outside the time window to file
                        if (j > 0)
                            for i = 1:j
                                fprintf(yfile, '%f %f\n', [y_times(i) y_fields(i)]);
                            end
                        end
                        
                        % Remove cutoff times from window arrays; append new value to window arrays
                        y_times = [y_times((1+j):len); y_time];
                        y_fields = [y_fields((1+j):len); y_field];
                        
                    else
                        y_times = [y_time];
                        y_fields = [y_field];
                    end
                    
                    y_time = -1;
                end
                
                % Check for z measurement
                if ~(z_time == -1)
                    len = length(z_times);
                    if (len > 0)
                        
                        % Find times outside the time window
                        cutoff_t = z_time-app.Window.Value;
                        j = 0;
                        for i = 1:len
                            if (z_times(i) > cutoff_t)
                                break
                            else
                                j = j + 1;
                            end
                        end
                        
                        % Write times outside the time window to file
                        if (j > 0)
                            for i = 1:j
                                fprintf(zfile, '%f %f\n', [z_times(i) z_fields(i)]);
                            end
                        end
                        
                        % Remove cutoff times from window arrays; append new value to window arrays
                        z_times = [z_times((1+j):len); z_time];
                        z_fields = [z_fields((1+j):len); z_field];
                        
                    else
                        z_times = [z_time];
                        z_fields = [z_field];
                    end
                    
                    z_time = -1;
                end
                
                % Update plot
                plot(app.FieldsPlot, x_times, x_fields, y_times, y_fields, z_times, z_fields)
                
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                
                % Optional pause
                pause(app.RecordmsEditField.Value/1000);
            end
            
            % Explicitely return x fields to zero. Return local control after setting y fields to zero
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fprintf(xy,'%s\n', 'CHAN 1');
            fscanf(xy);
            
            % Sweep to zero
            fprintf(xy,'%s\n', 'SWEEP ZERO FAST');
            fscanf(xy);
            fprintf(xy,'%s\n', 'IOUT?');
            fscanf(xy);
            % Use regex to extract number from string
            B = 1000*str2double(regexp(fscanf(xy),'[-+]?[0-9]*\.?[0-9]+([eE][-+]?[0-9]+)?','match'));
            while (abs(B) > app.ToleranceGEditField.Value)
                pause(app.RecordmsEditField.Value/1000);
                
                fprintf(xy,'%s\n', 'IOUT?');
                fscanf(xy);
                % Use regex to extract number from string
                B = 1000*str2double(regexp(fscanf(xy),'[-+]?[0-9]*\.?[0-9]+([eE][-+]?[0-9]+)?','match'));
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Explicitely return y fields to zero and return local control
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            fprintf(xy,'%s\n', 'CHAN 2');
            fscanf(xy);
            
            % Sweep to zero
            fprintf(xy,'%s\n', 'SWEEP ZERO FAST');
            fscanf(xy);
            fprintf(xy,'%s\n', 'IOUT?');
            fscanf(xy);
            % Use regex to extract number from string
            B = 1000*str2double(regexp(fscanf(xy),'[-+]?[0-9]*\.?[0-9]+([eE][-+]?[0-9]+)?','match'));
            while (abs(B) > app.ToleranceGEditField.Value)
                pause(app.RecordmsEditField.Value/1000);
                
                fprintf(xy,'%s\n', 'IOUT?');
                fscanf(xy);
                % Use regex to extract number from string
                B = 1000*str2double(regexp(fscanf(xy),'[-+]?[0-9]*\.?[0-9]+([eE][-+]?[0-9]+)?','match'));
            end
            
            % Return local control
            fprintf(xy,'%s\n', 'LOCAL');
            fscanf(xy);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Explicitely return z fields to zero and return local control
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Sweep to zero
            fprintf(z,'%s\n', 'SWEEP ZERO FAST');
            fscanf(z);
            fprintf(z,'%s\n', 'IOUT?');
            fscanf(z);
            % Use regex to extract number from string
            B = 1000*str2double(regexp(fscanf(z),'[-+]?[0-9]*\.?[0-9]+([eE][-+]?[0-9]+)?','match'));
            while (abs(B) > app.ToleranceGEditField.Value)
                pause(app.RecordmsEditField.Value/1000);
                
                fprintf(z,'%s\n', 'IOUT?');
                fscanf(z);
                % Use regex to extract number from string
                B = 1000*str2double(regexp(fscanf(z),'[-+]?[0-9]*\.?[0-9]+([eE][-+]?[0-9]+)?','match'));
            end
            
            % Return local control
            fprintf(z,'%s\n', 'LOCAL');
            fscanf(z);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Shut down serial port objects
            fclose(xy);
            fclose(z);
            
            % Write data to file
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            % Close temporary files
            fclose(xfile); fclose(yfile); fclose(zfile);
            
            % Reopen temporary files for reading
            xfile = fopen('xfile.txt', 'r');
            yfile = fopen('yfile.txt', 'r');
            zfile = fopen('zfile.txt', 'r');
            
            % Open file to save x, y and z data
            fileHandle = fopen(strrep(app.AppendToEditField.Value, "\", "/"), 'a');
            
            x_do = 1; y_do = 1; z_do = 1; % 0 if no data left
            x_winlen = length(x_times); y_winlen = length(y_times); z_winlen = length(z_times); % Number of window array data
            i = 1; j = 1; k = 1; % Position in window array
            
            % Append data to main file line by line
            while (x_do+y_do+z_do > 0)
                % If there is x data
                if x_do
                    if ~feof(xfile)
                        xappendage = strcat([fgetl(xfile), ' ']);
                    else
                        if (i <= x_winlen)
                            xappendage = strcat([num2str(x_times(i)), ' ', num2str(x_fields(i)), ' ']);
                            i = i+1;
                        else
                            x_do = 0;
                        end
                    end
                else
                    xappendage = '- - ';
                end
                
                % If there is y data
                if y_do
                    if ~feof(yfile)
                        yappendage = strcat([fgetl(yfile), ' ']);
                    else
                        if (j <= y_winlen)
                            yappendage = strcat([num2str(y_times(j)), ' ', num2str(y_fields(j)), ' ']);
                            j = j+1;
                        else
                            y_do = 0;
                        end
                    end
                else
                    yappendage = '- - ';
                end
                
                % If there is z data
                if z_do
                    if ~feof(zfile)
                        zappendage = fgetl(zfile);
                    else
                        if (k <= z_winlen)
                            zappendage = strcat([num2str(z_times(k)), ' ', num2str(z_fields(k))]);
                            k = k+1;
                        else
                            z_do = 0;
                        end
                    end
                else
                    zappendage = '- -';
                end
                
                % Write data to file
                fwrite(fileHandle, strcat([xappendage, yappendage, zappendage, newline]));
                
            end
            
            % Close files
            fclose(xfile);
            fclose(yfile);
            fclose(zfile);
            fclose(fileHandle);
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end

        % Cell edit callback: xFieldTable
        function xFieldTableCellEdit(app, event)
            
            % Count number of rows
            [num_rows, c] = size(app.xFieldTable.Data);
            
            % Check that the last row is being edited
            if ~(event.Indices(1) == num_rows)
                return ;
            end
            
            % Check if all of the last row's cells have been filled
            for i = [event.Indices(1) event.Indices(1)+num_rows event.Indices(1)+2*num_rows]
                if (app.xFieldTable.Data{i} == "")
                    return ;
                end
            end
            % If all the cells have been filled, add a new row
            app.xFieldTable.Data = [app.xFieldTable.Data; {'' '' ''}];
        end

        % Cell edit callback: yFieldTable
        function yFieldTableCellEdit(app, event)
            % Count number of rows
            [num_rows, c] = size(app.yFieldTable.Data);
            
            % Check that the last row is being edited
            if ~(event.Indices(1) == num_rows)
                return ;
            end
            
            % Check if all of the last row's cells have been filled
            for i = [event.Indices(1) event.Indices(1)+num_rows event.Indices(1)+2*num_rows]
                if (app.yFieldTable.Data{i} == "")
                    return ;
                end
            end
            % If all the cells have been filled, add a new row
            app.yFieldTable.Data = [app.yFieldTable.Data; {'' '' ''}];
        end

        % Cell edit callback: zFieldTable
        function zFieldTableCellEdit(app, event)
            % Count number of rows
            [num_rows, c] = size(app.zFieldTable.Data);
            
            % Check that the last row is being edited
            if ~(event.Indices(1) == num_rows)
                return ;
            end
            
            % Check if all of the last row's cells have been filled
            for i = [event.Indices(1) event.Indices(1)+num_rows event.Indices(1)+2*num_rows]
                if (app.zFieldTable.Data{i} == "")
                    return ;
                end
            end
            % If all the cells have been filled, add a new row
            app.zFieldTable.Data = [app.zFieldTable.Data; {'' '' ''}];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SFieldJeffreyMEdeUIFigure = uifigure;
            app.SFieldJeffreyMEdeUIFigure.Name = 'SField - Jeffrey M. Ede';
            app.xFieldTable = uitable(app.SFieldJeffreyMEdeUIFigure);
            app.xFieldTable.ColumnName = {'Bx(t) in G'; 'Time (s)'; 'Steps'};
            app.xFieldTable.RowName = {};
            app.xFieldTable.ColumnEditable = true;
            app.xFieldTable.CellEditCallback = createCallbackFcn(app, @xFieldTableCellEdit, true);
            app.SweepFieldButton = uibutton(app.SFieldJeffreyMEdeUIFigure, 'push');
            app.SweepFieldButton.ButtonPushedFcn = createCallbackFcn(app, @sweep_fields, true);
            app.SweepFieldButton.Text = 'Sweep Field';
            app.SaveSettingsButton = uibutton(app.SFieldJeffreyMEdeUIFigure, 'push');
            app.SaveSettingsButton.Text = 'Save Settings';
            app.LoadSettingsButton = uibutton(app.SFieldJeffreyMEdeUIFigure, 'push');
            app.LoadSettingsButton.Text = 'Load Settings';
            app.yFieldTable = uitable(app.SFieldJeffreyMEdeUIFigure);
            app.yFieldTable.ColumnName = {'By(t) in G'; 'Time (s)'; 'Steps'};
            app.yFieldTable.RowName = {};
            app.yFieldTable.ColumnEditable = true;
            app.yFieldTable.CellEditCallback = createCallbackFcn(app, @yFieldTableCellEdit, true);
            app.zFieldTable = uitable(app.SFieldJeffreyMEdeUIFigure);
            app.zFieldTable.ColumnName = {'Bz(t) in G'; 't_start'; 'Steps'};
            app.zFieldTable.RowName = {};
            app.zFieldTable.ColumnEditable = true;
            app.zFieldTable.CellEditCallback = createCallbackFcn(app, @zFieldTableCellEdit, true);
            app.SaveSweepButton = uibutton(app.SFieldJeffreyMEdeUIFigure, 'push');
            app.SaveSweepButton.Text = 'Save Sweep';
            app.HelpButton = uibutton(app.SFieldJeffreyMEdeUIFigure, 'push');
            app.HelpButton.Text = 'Help';
            app.DropDownLabel = uilabel(app.SFieldJeffreyMEdeUIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.SFieldJeffreyMEdeUIFigure);
            app.DropDown.Items = {'Field (kG)', 'Current (A)'};
            app.DropDown.Value = 'Field (kG)';
            app.ToleranceGEditFieldLabel = uilabel(app.SFieldJeffreyMEdeUIFigure);
            app.ToleranceGEditFieldLabel.Text = 'Tolerance (G)';
            app.ToleranceGEditField = uieditfield(app.SFieldJeffreyMEdeUIFigure, 'numeric');
            app.ToleranceGEditField.Limits = [0 Inf];
            app.ToleranceGEditField.Value = 50;
            app.RecordmsEditFieldLabel = uilabel(app.SFieldJeffreyMEdeUIFigure);
            app.RecordmsEditFieldLabel.Text = 'Record (ms)';
            app.RecordmsEditField = uieditfield(app.SFieldJeffreyMEdeUIFigure, 'numeric');
            app.RecordmsEditField.Limits = [0.001 Inf];
            app.RecordmsEditField.Value = 20;
            app.PauseButton = uibutton(app.SFieldJeffreyMEdeUIFigure, 'push');
            app.PauseButton.Text = 'Pause';
            app.FieldsPlot = uiaxes(app.SFieldJeffreyMEdeUIFigure);
            xlabel(app.FieldsPlot, 'Time (s)')
            ylabel(app.FieldsPlot, 'Field (G)')
            app.WindowsEditFieldLabel = uilabel(app.SFieldJeffreyMEdeUIFigure);
            app.WindowsEditFieldLabel.Text = 'Window (s)';
            app.Window = uieditfield(app.SFieldJeffreyMEdeUIFigure, 'numeric');
            app.Window.Value = 10;
            app.AppendToEditFieldLabel = uilabel(app.SFieldJeffreyMEdeUIFigure);
            app.AppendToEditFieldLabel.Text = 'Append To';
            app.AppendToEditField = uieditfield(app.SFieldJeffreyMEdeUIFigure, 'text');
            app.AppendToEditField.Value = 'D:\Programmes\SField\temp.txt';
            app.ClearFileButton = uibutton(app.SFieldJeffreyMEdeUIFigure, 'push');
            app.ClearFileButton.Text = 'Clear File';
            app.ProgressLabel = uilabel(app.SFieldJeffreyMEdeUIFigure);
            app.ProgressLabel.Text = 'Progress (%)';
            app.ProgressGauge = uigauge(app.SFieldJeffreyMEdeUIFigure, 'circular');
        end
    end

    methods (Access = public)

        % Construct app
        function app = Mag_Field_Control

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SFieldJeffreyMEdeUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SFieldJeffreyMEdeUIFigure)
        end
    end
end